classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        TabGroup                    matlab.ui.container.TabGroup
        AcquisizioneDatiTab         matlab.ui.container.Tab
        GridLayout                  matlab.ui.container.GridLayout
        SalvaCSVButton              matlab.ui.control.Button
        RipetizioneLabel            matlab.ui.control.Label
        ManoDropDown                matlab.ui.control.DropDown
        ManoDropDownLabel           matlab.ui.control.Label
        GestoDropDown               matlab.ui.control.DropDown
        GestoDropDownLabel          matlab.ui.control.Label
        StopButton                  matlab.ui.control.Button
        StartButton                 matlab.ui.control.Button
        ModelloSmartphoneEditField  matlab.ui.control.EditField
        ModelloSmartphoneLabel      matlab.ui.control.Label
        IDEditField                 matlab.ui.control.EditField
        IDEditFieldLabel            matlab.ui.control.Label
        OrientationAxes             matlab.ui.control.UIAxes
        AccAxes                     matlab.ui.control.UIAxes
        GyroAxes                    matlab.ui.control.UIAxes
        MagAxes                     matlab.ui.control.UIAxes
        ClassificazioneGestiTab     matlab.ui.container.Tab
    end

    properties (Access = private)
        RealTimeTimer;          % Timer per aggiornamento grafici
        SignalsStruct struct;   % Struttura per salvare i dati
        Smartphone;   % Oggetto per la connessione allo smartphone
        IsLogging logical = false; % Flag di registrazione
        TrainedModel;           % Modello per la classificazione (scheda 2)
        %contatori
        CurrentSubject struct
        CurrentGesture = 1
        CurrentRepetition = 1
        TotalGestures = 25
    end
    
    methods (Access = private)
        function updateRealTimePlots(app)
            try
                if app.IsLogging && isvalid(app.Smartphone)
                    % Leggi i dati con i rispettivi tempi
                    [acc, t_acc] = accellog(app.Smartphone);
                    [gyro, t_gyro] = angvellog(app.Smartphone);
                    [mag, t_mag] = magfieldlog(app.Smartphone);
                    [orient, t_orient] = orientlog(app.Smartphone);
            
                    % Ottieni gli assi tramite Tag
                    accAxes = findobj(app.UIFigure, 'Tag', 'AccAxes');
                    gyroAxes = findobj(app.UIFigure, 'Tag', 'GyroAxes');
                    magAxes = findobj(app.UIFigure, 'Tag', 'MagAxes');
                    orientAxes = findobj(app.UIFigure, 'Tag', 'OrientationAxes');
            
                    % Plot Accelerometro
                    if ~isempty(acc) && isvalid(accAxes)
                        plot(accAxes, t_acc, acc(:,1), 'r', t_acc, acc(:,2), 'g', t_acc, acc(:,3), 'b');
                        title(accAxes, 'Accelerometro');
                        grid(accAxes, 'on');
                    end
            
                    % Plot Giroscopio
                    if ~isempty(gyro) && isvalid(gyroAxes)
                        plot(gyroAxes, t_gyro, gyro(:,1), 'r', t_gyro, gyro(:,2), 'g', t_gyro, gyro(:,3), 'b');
                        title(gyroAxes, 'Giroscopio');
                        grid(gyroAxes, 'on');
                    end
            
                    % Plot Magnetometro
                    if ~isempty(mag) && isvalid(magAxes)
                        plot(magAxes, t_mag, mag(:,1), 'r', t_mag, mag(:,2), 'g', t_mag, mag(:,3), 'b');
                        title(magAxes, 'Magnetometro');
                        grid(magAxes, 'on');
                    end
            
                    % Plot Orientazione
                    if ~isempty(orient) && isvalid(orientAxes)
                        plot(orientAxes, t_orient, orient(:,1), 'r', t_orient, orient(:,2), 'g', t_orient, orient(:,3), 'b');
                        title(orientAxes, 'Orientazione');
                        grid(orientAxes, 'on');
                    end
            
                    drawnow limitrate;
                end
            catch ME
                disp(['Errore: ' ME.message]);
            end
        end
        
        function deleteTimer(app)
            if ~isempty(app.RealTimeTimer)
                stop(app.RealTimeTimer);
                delete(app.RealTimeTimer);
                app.RealTimeTimer = [];
            end
        end

        function saveAcquisition(app, data)
            subjectID = app.IDEditField.Value;
            gestureClass = app.GestoDropDown.Value;
            
            if ~isfield(app.SignalsStruct, subjectID)
                app.SignalsStruct.(subjectID) = struct();
            end
            
            if ~isfield(app.SignalsStruct.(subjectID), gestureClass)
                app.SignalsStruct.(subjectID).(gestureClass) = struct();
            end
            
            repField = sprintf('Rep%d', app.CurrentRepetition);
            app.SignalsStruct.(subjectID).(gestureClass).(repField) = data;
            
            % Aggiorna contatori
            if app.CurrentRepetition < 5
                app.CurrentRepetition = app.CurrentRepetition + 1;
            else
                app.CurrentRepetition = 1;
                app.CurrentGesture = app.CurrentGesture + 1;
                if app.CurrentGesture > app.TotalGestures
                    msgbox('Acquisizione completata per tutte le classi!');
                else
                    app.GestoDropDown.Value = sprintf('Class %d', app.CurrentGesture-1);
                end
            end
        end
        
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            try
                % Verifica se esiste già un oggetto valido
                if isempty(app.Smartphone) || ~isvalid(app.Smartphone)
                    app.Smartphone = mobiledev; % Crea nuovo oggetto
                end
        
                % Configurazione sensori
                app.Smartphone.SampleRate = 100;
                app.Smartphone.AccelerationSensorEnabled = 1;
                app.Smartphone.AngularVelocitySensorEnabled = 1;
                app.Smartphone.MagneticSensorEnabled = 1;
                app.Smartphone.OrientationSensorEnabled = 1;
        
                % Timer
                app.RealTimeTimer = timer(...
                    'ExecutionMode', 'fixedRate', ...
                    'Period', 0.1, ...
                    'TimerFcn', @(~,~) app.updateRealTimePlots());
                start(app.RealTimeTimer);
        
                % Inizia registrazione
                app.Smartphone.Logging = 1;
                app.IsLogging = true;
                app.StopButton.Enable = 'on';
                app.StartButton.Enable = 'off';
        
            catch ME
                errordlg(sprintf('Errore: %s', ME.message));
            end        
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            % Ferma registrazione e timer
            stop(app.RealTimeTimer);
            app.Smartphone.Logging = 0;
            app.IsLogging = false;
    
            % Salva i dati nella struttura
            [acc, ~] = accellog(app.Smartphone);
            [gyro, ~] = angvellog(app.Smartphone);
            [mag, ~] = magfieldlog(app.Smartphone);
            [orientation, ~] = orientlog(app.Smartphone);
    
            % Taglia a 250 campioni e salva
            acquisition = struct(...
                'acc', acc(1:250,:), ...
                'gyro', gyro(1:250,:), ...
                'mag', mag(1:250,:), ...
                'orientation', orientation(1:250,:), ...
                'GestureID', app.GestoDropDown.Value, ...
                'Hand', app.ManoDropDown.Value, ...
                'SmartphoneModel', app.ModelloSmartphoneEditField.Value);
    
            subjectID = app.IDEditField.Value;

            % Se la struttura SignalsStruct è vuota, inizializzala
            if isempty(fieldnames(app.SignalsStruct))
                app.SignalsStruct = struct();
            end

            % Se il soggetto non esiste, crea una nuova entry
            if ~isfield(app.SignalsStruct, subjectID)
                app.SignalsStruct.(subjectID) = struct();
            end

            % Genera un nome univoco per l'acquisizione
            acqName = ['acq_', datestr(now, 'HHMMSS')];

            % Aggiungi i dati usando la subscript notation
            app.SignalsStruct.(subjectID).(acqName) = acquisition;    
            % Reset UI
            app.StartButton.Enable = 'on';
            app.StopButton.Enable = 'off';            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.AcquisizioneDatiTab = uitab(app.TabGroup);
            app.AcquisizioneDatiTab.Title = 'Acquisizione Dati';
            app.GridLayout = uigridlayout(app.AcquisizioneDatiTab);
            app.MagAxes = uiaxes(app.GridLayout);
            title(app.MagAxes, 'Magnetometro')
            xlabel(app.MagAxes, 'Tempo [s]')
            ylabel(app.MagAxes, 'μT')
            zlabel(app.MagAxes, 'Z')
            app.MagAxes.Tag = 'MagAxes';
            app.GyroAxes = uiaxes(app.GridLayout);
            title(app.GyroAxes, 'Giroscopio')
            xlabel(app.GyroAxes, 'Tempo [s]')
            ylabel(app.GyroAxes, 'rad/s')
            zlabel(app.GyroAxes, 'Z')
            app.GyroAxes.Tag = 'GyroAxes';
            app.AccAxes = uiaxes(app.GridLayout);
            title(app.AccAxes, 'Accelerometro')
            xlabel(app.AccAxes, 'Tempo [s]')
            ylabel(app.AccAxes, 'm/s²')
            zlabel(app.AccAxes, 'Z')
            app.AccAxes.Tag = 'AccAxes';
            app.OrientationAxes = uiaxes(app.GridLayout);
            title(app.OrientationAxes, 'Orientazione')
            xlabel(app.OrientationAxes, 'Tempo [s]')
            ylabel(app.OrientationAxes, 'Gradi [°]')
            zlabel(app.OrientationAxes, 'Z')
            app.OrientationAxes.Tag = 'OrientationAxes';
            app.IDEditFieldLabel = uilabel(app.GridLayout);
            app.IDEditFieldLabel.Text = 'ID';
            app.IDEditField = uieditfield(app.GridLayout, 'text');
            app.ModelloSmartphoneLabel = uilabel(app.GridLayout);
            app.ModelloSmartphoneLabel.Text = 'ModelloSmartphone';
            app.ModelloSmartphoneEditField = uieditfield(app.GridLayout, 'text');
            app.StartButton = uibutton(app.GridLayout, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.StopButton = uibutton(app.GridLayout, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.GestoDropDownLabel = uilabel(app.GridLayout);
            app.GestoDropDownLabel.Text = 'Gesto';
            app.GestoDropDown = uidropdown(app.GridLayout);
            app.GestoDropDown.Items = {'Class 0', 'Class 1', 'Class 2', 'Class 3', 'Class 4', 'Class 5', 'Class 6', 'Class 7', 'Class 8', 'Class 9', 'Class 10', 'Class 11', 'Class 12', 'Class 13', 'Class 14', 'Class 15', 'Class 16', 'Class 17', 'Class 18', 'Class 19', 'Class 20', 'Class 21', 'Class 22', 'Class 23', 'Class 24'};
            app.GestoDropDown.Value = 'Class 0';
            app.ManoDropDownLabel = uilabel(app.GridLayout);
            app.ManoDropDownLabel.Text = 'Mano';
            app.ManoDropDown = uidropdown(app.GridLayout);
            app.ManoDropDown.Items = {'dx', 'sx'};
            app.ManoDropDown.Value = 'dx';
            app.RipetizioneLabel = uilabel(app.GridLayout);
            app.RipetizioneLabel.Text = 'Ripetizione';
            app.SalvaCSVButton = uibutton(app.GridLayout, 'push');
            app.SalvaCSVButton.Text = 'Salva CSV';
            app.ClassificazioneGestiTab = uitab(app.TabGroup);
            app.ClassificazioneGestiTab.Title = 'Classificazione Gesti';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end